classdef faltung_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Hyperlink            matlab.ui.control.Hyperlink
        Image                matlab.ui.control.Image
        VerschiebunggtLabel  matlab.ui.control.Label
        Kurvenform2DropDown  matlab.ui.control.DropDown
        gtLabel              matlab.ui.control.Label
        Kurvenform1DropDown  matlab.ui.control.DropDown
        ftLabel              matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        UIAxesCorr           matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        tv % time vector
        funNames = ["rectpulse_1","rectpulse_2","rectpulse_3","rectpulse_4","tripulse_1", "tripulse_3", "tripulse_4"];
        % function human readable names
        funDescNames = ["Rechteckpuls", "Rechteckpuls mit halber Amplitude", "unsymmetrischer Rechteckpuls, Breite 1","unsymmetrischer Rechteckpuls, Breite 2", "Dreieckspuls", "Dreieckspuls #3", "Dreieckspuls #4"];
        f1 %function 1
        f2 % function 2
        fnum1
        fnum2 
        fnum2mirr
        lags    % correlation lag vector
        cf      % correlation values vectord
        ts      % time shift (kinda tau)
        timestep = 0.01;
        pausefig
    end
    
    methods (Access = private)
        
        function results = updateGFX(app)
            % shift function 2
            
            %app.fnum2 = app.f2(app.tv+app.ts*-1);
            % create mirrored version of function 2
            app.fnum2mirr = app.f2(-app.tv+app.ts);
            
            % re-plot functions 1 and 2
            % plot functions 1 and 2
            plot(app.UIAxes, app.tv, app.fnum1,'b')
            hold(app.UIAxes, 'on');
            % skip plotting the (non-mirrored, original) function #2
            %plot(app.UIAxes,app.tv,app.fnum2)
            % plot the mirrored one, instead
            plot(app.UIAxes,app.tv,app.fnum2mirr, 'color',[0.93, 0.69, 0.13])
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, "f", "g mirrored, shifted")
            
            % we've alrady done the convolution computation
            % since this sometimes takes a while
            % so we only do it once and
            % now, only plot the part corresponding to the
            % amount of time-shift that was selected
            
            % TODO: 
            % update line below for handling flexible length lags vectos
            % (1001 = zero position, calculate dynamically)            
            % plot with zeroes padded
          
            %plot(app.UIAxesCorr,app.lags(1:1001-app.ts*-1/app.timestep)*app.timestep,  app.cf(1:1001-app.ts*-1/app.timestep))
            % generate time vector for plotting
            % matlab's xcorr will give us an appropriate vector
            % conv will not. so we do this ourselves...
            % we obtain double the amount of points from doing the
            % convolution
            % tvc = time vector, convolution (result)
            tvc = [2*min(app.tv):app.timestep:2*max(app.tv)];
            %plot(app.UIAxesCorr,tvc(1:1001-app.ts*-1/app.timestep)*app.timestep,  app.cf(1:1001-app.ts*-1/ app.timestep))
            app.ts
            app.ts*-1/ app.timestep
            % this line implicitly assumes having symmetric signals
            % (centered around zero) 
            % doooooh. Fixme...
            %plot(app.UIAxesCorr,tvc(1:1001-app.ts*-1/app.timestep) ,app.cf(1:1001 - app.ts*-1/ app.timestep))
            
            plot(app.UIAxesCorr,tvc(1:1001-app.ts*-1/app.timestep) ,app.cf(1:1001 - app.ts*-1/ app.timestep))
            
            xlim(app.UIAxesCorr, [-10,10]);
            ylim(app.UIAxesCorr, [0,max(app.cf)]);
        end
        
        function results = AlertMsgCloseFcn(app,src,event)
            uiresume();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set time vector
            app.tv = -5:0.01:5;
            % Cool, wir generieren uns ein function handle f,
            % dem wir dann verschiedene funktionen zuweisen können.
            % ist eigentlich eine echt elegante lösung :)
            %app.n = app.OrdnungApproximationSpinner.Value;
            % start with first function defined
            %app.f = @saegezahn;
            % generate function handle from name defined in function names array
            % this will end badly if the files don't exist or names have
            % been mis-typed
            % set both function names to be the same (autocorrelation)
            app.f1 = eval(strcat("@",app.funNames(1)));   
            app.f2 = eval(strcat("@",app.funNames(1)));
             % populate Drop Down Function Selector
            app.Kurvenform1DropDown.Items = app.funDescNames;
            app.Kurvenform2DropDown.Items = app.funDescNames;            
            % nice trick.
            % will be useful for indexing the functions in funNames
            app.Kurvenform1DropDown.ItemsData = 1:length(app.Kurvenform1DropDown.Items);
            app.Kurvenform2DropDown.ItemsData = 1:length(app.Kurvenform2DropDown.Items);
            % start with a shift of -3
            app.ts = -3;
            app.fnum1 = app.f1(app.tv);
            % don't shift function 2, yet
            % we trick our way around re-calculation of 
            % the correlation, so we need it non-shifted
            app.fnum2 = app.f2(app.tv);
            % the mirrored function will is calculated (now)
            app.fnum2mirr = app.f2(-app.tv);
           
            % do correlation computation
            % we do this once...
            % because it takes time 
            % and it doesn't really change
            % so we save time and avoid a laggy user experience
            % when updating the display
            % this is a bit of a trick but
            % since the app is more about the teaching aspect of
            % how the convolution happens / looks like i guess that's ok...
            app.cf = conv( app.fnum1, app.fnum2);
            
            %app.fnum2 = app.f2(app.tv+app.ts);
            % we don't call updateGFX because we want the user to stare at
            % the functions for a while (start-up animation of mirrored
            % function)
            %app.updateGFX();     
            % plot functions 1 and 2
            plot(app.UIAxes, app.tv, app.fnum1,'b')
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,app.tv,app.fnum2,'r')
            legend(app.UIAxes, "f","g");
            %uialert(app.UIFigure,'Watch the function being mirrored','Pay attention...','Modal',false, 'CloseFcn',@(src,event)AlertMsgCloseFcn(app,src,event));
            %uialert(app.UIFigure,'Watch the second function (red) being mirrored. A yellow curve will appear. The original function (red) will be erased hereafter.','Pay attention...','Modal',false, 'CloseFcn','uiresume(app.UIFigure)');
            uialert(app.UIFigure,'Achten Sie auf die Spiegelung der zweiten (roten) Funktion. Eine gelbe Kurve wird erscheinen... Die Originalfunktion (rot) wird verschwinden.','Achtung...','Modal',false, 'CloseFcn','uiresume(app.UIFigure)');
            uiwait(app.UIFigure,5.0);
                        
            disp('Program execution resumed.');
            plot(app.UIAxes,app.tv,app.fnum2mirr,'color',[0.93, 0.69, 0.13])
            
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, "f","g", "g mirrored")
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            % doooh... 
            % we can't specify integer values only (or step size)
            % for a slider.
            % so we need to hack our way around that limitation
            % https://de.mathworks.com/matlabcentral/answers/503673-app-designer-create-a-discrete-slider
            % determine which discrete option the current value is closest to.        
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            
            % move the slider to that option            
            event.Source.Value = event.Source.MajorTicks(minIdx);
            % Override the selected value if you plan on    
            % using it within this function
            value = event.Source.MajorTicks(minIdx);
            
            % set time shift value
            app.ts = value;
            
            % do plot update
            app.updateGFX();            
        end

        % Value changed function: Kurvenform1DropDown
        function Kurvenform1DropDownValueChanged(app, event)
            value = app.Kurvenform1DropDown.Value;
            app.f1 = eval(strcat("@",app.funNames(value)));  
            app.fnum1 = app.f1(app.tv);
            % do correlation computation
            % we only do this once and
            % just plot the appropraiate parts of the result
            % according to slider setting 
            % this speeds up updating the display
            app.cf = conv( app.fnum1, app.fnum2);
            
            
            % reset timeshift of function #2
            app.Slider.Value = 0;
            app.ts = 0;
            
            app.updateGFX();
        end

        % Value changed function: Kurvenform2DropDown
        function Kurvenform2DropDownValueChanged(app, event)
            value = app.Kurvenform2DropDown.Value;
            app.f2 = eval(strcat("@",app.funNames(value)));
            % for correlation computation we keep the
            % function centered around t=0
            % we'll shift it according to slider value
            % later
            app.fnum2 = app.f2(app.tv);            
            % do correlation computation
            % we only do this once and
            % just plot the appropraiate parts of the result
            % according to slider setting 
            % this speeds up updating the display
            app.cf = conv( app.fnum1, app.fnum2);
            % now, shift function 2 according to slider value for plotting
            % app.fnum2 = app.f2(app.tv+app.ts*-1);
            app.fnum2mirr = app.f2(-app.tv);
            % don't use the plot update function
            % we want to hightlight the process of mirroring the second
            % function
            %app.updateGFX();
            
            % plot functions 1 and 2
            plot(app.UIAxes, app.tv, app.fnum1,'b')
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,app.tv,app.fnum2,'r')
            
            legend(app.UIAxes, "f","g");            
            %uialert(app.UIFigure,'Watch the second function (red) being mirrored. A yellow curve will appear. The original function (red) will be erased hereafter.','Pay attention...','Modal',false, 'CloseFcn','uiresume(app.UIFigure)');
            uialert(app.UIFigure,'Achten Sie auf die Spiegelung der zweiten (roten) Funktion. Eine gelbe Kurve wird erscheinen... Die Originalfunktion (rot) wird verschwinden.','Achtung...','Modal',false, 'CloseFcn','uiresume(app.UIFigure)');
            uiwait(app.UIFigure,5.0);
            
            plot(app.UIAxes,app.tv,app.fnum2mirr,'color',[0.93, 0.69, 0.13])            
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, "f","g", "g mirrored")
            
            % reset time shift
            app.Slider.Value = 0;
            app.ts = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signale')
            xlabel(app.UIAxes, '\tau')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesCorr = uiaxes(app.UIFigure);
            title(app.UIAxesCorr, 'Ablauf/Verlauf der Faltung')
            xlabel(app.UIAxesCorr, 't')
            ylabel(app.UIAxesCorr, 'Y')
            zlabel(app.UIAxesCorr, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-5 5];
            app.Slider.MajorTicks = [-5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.Slider.MajorTickLabels = {'5', '4.5', '4', '3.5', '3', '2.5', '2', '1.5', '1', '0.5', '0', '-0.5', '-1', '-1.5', '-2', '-2.5', '-3', '-3.5', '-4', '-4.5', '-5'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.ftLabel = uilabel(app.UIFigure);
            app.ftLabel.Text = 'f(t)';
            app.Kurvenform1DropDown = uidropdown(app.UIFigure);
            app.Kurvenform1DropDown.ValueChangedFcn = createCallbackFcn(app, @Kurvenform1DropDownValueChanged, true);
            app.gtLabel = uilabel(app.UIFigure);
            app.gtLabel.Text = 'g(t)';
            app.Kurvenform2DropDown = uidropdown(app.UIFigure);
            app.Kurvenform2DropDown.ValueChangedFcn = createCallbackFcn(app, @Kurvenform2DropDownValueChanged, true);
            app.VerschiebunggtLabel = uilabel(app.UIFigure);
            app.VerschiebunggtLabel.Text = 'Verschiebung g(t)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screenshot_2021-04-16 Convolution - Wikipedia.png';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://de.wikipedia.org/wiki/Faltung_(Mathematik)';
            app.Hyperlink.Text = 'Artikel Faltung Wikipedia';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = faltung_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end